classdef song_converter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Button                 matlab.ui.control.Button           % Select ...
        ButtonGroup            matlab.ui.container.ButtonGroup    % What wo...
        RadioButton            matlab.ui.control.RadioButton      % Remove ...
        RadioButton2           matlab.ui.control.RadioButton      % Lower f...
        RadioButton3           matlab.ui.control.RadioButton      % nothere
        Panel                  matlab.ui.container.Panel          % Range o...
        LabelNumericEditField  matlab.ui.control.Label            % Startin...
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Ending ...
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        Panel2                 matlab.ui.container.Panel          % Select ...
        CheckBox               matlab.ui.control.CheckBox         % Select ...
        LabelNumericEditField3 matlab.ui.control.Label            % Select ...
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label2                 matlab.ui.control.Label            % Select ...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label3                 matlab.ui.control.Label            % OR
        CheckBox2              matlab.ui.control.CheckBox         % Apply t...
    end


    properties (Access = private)
        Y                 % Share input data across the app 
        FS
        Frequency1
        Frequency2
        Time1
        Time2
    end

    methods (Access = private)
        
        function filtered = filtering(app,Fs, y1, f1, f2)
            Fn = Fs/2;      % Nyguist Frequency
            F1 = f1/Fn;     % Normalize frequencies 
            F2 = f2/Fn;
            
            d = fir1(300,[F1 F2],'stop'); % Design Filter 
            filtered = filter(d,1,y1);    % Apply filter
            
            %figure(1)
            %freqz(d,1,2048,Fs)            % Frequency response of filter 
            
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Panel.Visible = 'off';
           app.Panel2.Visible = 'off';
           app.RadioButton3.Visible = 'off';
        end

        % Button button pushed function
        function ButtonPushed(app)
            [file,path] = uigetfile('*.wav'); % open a wav file
            [y,fs] = audioread(file);        % store input data 
            x = size(y);             % get dimensions of array
            z = x(1,2);       
            app.Y = y;
            app.FS = fs;
            
            if z == 1           % test if the song is stereo
                f = errordlg('Try another file in stereo','File Error') % error message 
            end

         
        end

        % ButtonGroup selection change function
        function ButtonGroupSelectionChanged(app, event)
          %  selectedButton = app.ButtonGroup.SelectedObject;
          if app.RadioButton2.Value == true        % if user selects, show next panel
              app.Panel.Visible = 'on';        
          else
               app.Panel.Visible = 'off';
          end
          
          if app.RadioButton.Value == true
              left = app.Y(:,1);                % seperate each channel
              right = app.Y(:,2);
             % soundsc(left-right,app.FS)
             unvoiced = left - right;            % remove the common part from both channels
             [file,path] = uiputfile('*.wav');   
             audiowrite(file,unvoiced,app.FS)    % write wav file 
             
          end
        end

        % NumericEditField3 value changed function
        function NumericEditField3ValueChanged(app)
            app.Time1 = app.NumericEditField3.Value;
            
        end

        % NumericEditField value changed function
        function NumericEditFieldValueChanged(app)
            frequency1 = app.NumericEditField.Value;
            app.Frequency1 = frequency1;
            x = exist('frequency');             % if user entered a value show next panel
            if x == 1
                app.Panel2.Visible = 'on'
            end
            
        end

        % NumericEditField2 value changed function
        function NumericEditField2ValueChanged(app)
            frequency2 = app.NumericEditField2.Value;
            app.Frequency2 = frequency2;
            y = exist('frequency2');            % if user entered a value show next panel
            if y == 1
                app.Panel2.Visible = 'on'
            end
            
        end

        % CheckBox value changed function
        function wholesongbox(app)
            value = app.CheckBox.Value;
            if value == true
                clean = filtering(app,app.FS,app.Y,app.Frequency1,app.Frequency2);  % filter the song
                [file,path] = uiputfile('*.wav');   
                audiowrite(file,clean,app.FS)    % write wav file 
            end
            app.CheckBox.Value = false;
        end

        % CheckBox2 value changed function
        function specificpartbox(app)
            value = app.CheckBox2.Value;
            
            if value == true 
               x1 = app.Time1 * app.FS;        % convert time to array position
               x2 = app.Time2 * app.FS;
               ynew = app.Y(x1:x2,:);          % select the part of the song to get filtered
               
               clean = filtering(app,app.FS,ynew,app.Frequency1,app.Frequency2);  % filter the song
               y1 = app.Y(1:x1,:);                % select the beginning part of the song 
               y2 = app.Y(x2:end,:);              % select the ending part of the song
               ylast = vertcat(y1,clean,y2);      % combine the three parts 
               
               [file,path] = uiputfile('*.wav');   
               audiowrite(file,ylast,app.FS)    % write wav file 
            end
            
        end

        % NumericEditField4 value changed function
        function NumericEditField4ValueChanged(app)
            app.Time2 = app.NumericEditField4.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed);
            app.Button.Text = 'Select song (stereo)';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'What would you want to apply?';
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Remove vocals';
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Lower frecuencies';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup);
            app.RadioButton3.Value = true;
            app.RadioButton3.Text = 'nothere';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Range of frequencies ';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Starting frequency';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @NumericEditFieldValueChanged);
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Ending frequency';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2.ValueChangedFcn = createCallbackFcn(app, @NumericEditField2ValueChanged);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Select time';
            app.Panel2.Units = 'pixels';
            app.CheckBox = uicheckbox(app.Panel2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @wholesongbox);
            app.CheckBox.Text = 'Select the whole song';
            app.LabelNumericEditField3 = uilabel(app.Panel2);
            app.LabelNumericEditField3.Text = 'Select starting time in secs';
            app.NumericEditField3 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @NumericEditField3ValueChanged);
            app.Label2 = uilabel(app.Panel2);
            app.Label2.Text = 'Select ending time in secs';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @NumericEditField4ValueChanged);
            app.Label3 = uilabel(app.Panel2);
            app.Label3.Text = 'OR';
            app.CheckBox2 = uicheckbox(app.Panel2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @specificpartbox);
            app.CheckBox2.Text = 'Apply to specific part of the song';
        end
    end

    methods (Access = public)

        % Construct app
        function app = song_converter()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end